classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxesTop                matlab.ui.control.UIAxes
        ConfigDropDownLabel      matlab.ui.control.Label
        ConfigDropDown           matlab.ui.control.DropDown
        RunButton                matlab.ui.control.Button
        UIParamTable             matlab.ui.control.Table
        Param1Slider             matlab.ui.control.Slider
        Param1Spinner            matlab.ui.control.Spinner
        UIAxesBottom             matlab.ui.control.UIAxes
        VariablesListBox_2Label  matlab.ui.control.Label
        VariablesListBoxBottom   matlab.ui.control.ListBox
        Param1DropDownLabel      matlab.ui.control.Label
        Param1DropDown           matlab.ui.control.DropDown
        VariablesListBoxLabel    matlab.ui.control.Label
        VariablesListBoxTop      matlab.ui.control.ListBox
        Param2Slider             matlab.ui.control.Slider
        Param2Spinner            matlab.ui.control.Spinner
        Param2DropDownLabel      matlab.ui.control.Label
        Param2DropDown           matlab.ui.control.DropDown
        Param3Slider             matlab.ui.control.Slider
        Param3Spinner            matlab.ui.control.Spinner
        Param3DropDownLabel      matlab.ui.control.Label
        Param3DropDown           matlab.ui.control.DropDown
        AutorunCheckBox          matlab.ui.control.CheckBox
        AnimationButton          matlab.ui.control.Button
        CustomPlotButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        physics_p;
        control_p;
        traj_p;
        sim_p;
        plot_p;
        param_values; % Description
    end
    
    methods (Access = private)
        
        function table_data = loadTableParameters(app, param_struct, name)
            all_values = struct2cell(param_struct);
            all_names = fieldnames(param_struct);
            
            idx = find(cellfun(@(x) (isnumeric(x) && isscalar(x)),all_values));        
            
            table_struct.types = repmat({name}, [length(idx) 1]);
            table_struct.names = all_names(idx);
            table_struct.values = cell2mat(all_values(idx));
            
            table_data = struct2table(table_struct);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startup();
            config_dir = dir('config');
            idx = endsWith({config_dir.name}, 'config.m')==1;
            config_list = {config_dir(idx).name};
            app.ConfigDropDown.Items = config_list;
            app.ConfigDropDownValueChanged();
%             app.param_values = table2array(app.UIParamTable.DisplayData(:,3));
        end

        % Value changed function: ConfigDropDown
        function ConfigDropDownValueChanged(app, event)
            value = app.ConfigDropDown.Value;
            config_fun = str2func(value(1:end-2)); % remove *.m
            [app.physics_p, app.control_p, app.traj_p, app.sim_p, app.plot_p] = config_fun();

            table = [];
            table = [table; app.loadTableParameters(app.physics_p, 'Physics')];
            table = [table; app.loadTableParameters(app.control_p, 'Control')];
            table = [table; app.loadTableParameters(app.traj_p, 'Trajectory')];
            table = [table; app.loadTableParameters(app.sim_p, 'Simulation')];
            table = [table; app.loadTableParameters(app.plot_p, 'Plot')];
            
            app.UIParamTable.Data = table;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            [t,x] = ode45(@(t, x) ode_fun(t, x, app.physics_p, app.control_p, app.traj_p, app.sim_p), app.sim_p.t, app.sim_p.x0);
            plot(app.UIAxesTop,t,x);
        end

        % Cell edit callback: UIParamTable
        function UIParamTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            newDisplayData = app.UIParamTable.DisplayData;
            
            i = indices(1);
            type = char(table2cell(newDisplayData(i,1)));
            field = char(table2cell(newDisplayData(i,2)));
            value = newData;
            
            if strcmp(type, 'Control')
                app.control_p.(field) = value;
            elseif strcmp(type, 'Physics')
                app.physics_p.(field) = value;
            elseif strcmp(type, 'Trajectory')
                app.traj_p.(field) = value;
            elseif strcmp(type, 'Simulation')
                app.sim_p.(field) = value;
            elseif strcmp(type, 'Plot')
                app.plot_p.(field) = value;
            end
            
            app.UIParamTable.ColumnEditable
            app.UIParamTable.Enable
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesTop = uiaxes(app.UIFigure);
            title(app.UIAxesTop, '', 'Interpreter', 'latex')
            xlabel(app.UIAxesTop, '', 'Interpreter', 'latex')
            ylabel(app.UIAxesTop, 'Y', 'Interpreter', 'latex')
            app.ConfigDropDownLabel = uilabel(app.UIFigure);
            app.ConfigDropDownLabel.Text = 'Config';
            app.ConfigDropDown = uidropdown(app.UIFigure);
            app.ConfigDropDown.Items = {};
            app.ConfigDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigDropDownValueChanged, true);
            app.ConfigDropDown.Value = {};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UIParamTable = uitable(app.UIFigure);
            app.UIParamTable.ColumnName = {'Type'; 'Name'; 'Value'};
            app.UIParamTable.RowName = {};
            app.UIParamTable.ColumnSortable = [true true false];
            app.UIParamTable.ColumnEditable = [false false true];
            app.UIParamTable.CellEditCallback = createCallbackFcn(app, @UIParamTableCellEdit, true);
            app.Param1Slider = uislider(app.UIFigure);
            app.Param1Spinner = uispinner(app.UIFigure);
            app.UIAxesBottom = uiaxes(app.UIFigure);
            title(app.UIAxesBottom, '', 'Interpreter', 'latex')
            xlabel(app.UIAxesBottom, 'Time [seconds]', 'Interpreter', 'latex')
            ylabel(app.UIAxesBottom, 'Y', 'Interpreter', 'latex')
            app.VariablesListBox_2Label = uilabel(app.UIFigure);
            app.VariablesListBox_2Label.Text = 'Variables';
            app.VariablesListBoxBottom = uilistbox(app.UIFigure);
            app.Param1DropDownLabel = uilabel(app.UIFigure);
            app.Param1DropDownLabel.Text = 'Param 1';
            app.Param1DropDown = uidropdown(app.UIFigure);
            app.VariablesListBoxLabel = uilabel(app.UIFigure);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.VariablesListBoxTop = uilistbox(app.UIFigure);
            app.Param2Slider = uislider(app.UIFigure);
            app.Param2Spinner = uispinner(app.UIFigure);
            app.Param2DropDownLabel = uilabel(app.UIFigure);
            app.Param2DropDownLabel.Text = 'Param 2';
            app.Param2DropDown = uidropdown(app.UIFigure);
            app.Param3Slider = uislider(app.UIFigure);
            app.Param3Spinner = uispinner(app.UIFigure);
            app.Param3DropDownLabel = uilabel(app.UIFigure);
            app.Param3DropDownLabel.Text = 'Param 3';
            app.Param3DropDown = uidropdown(app.UIFigure);
            app.AutorunCheckBox = uicheckbox(app.UIFigure);
            app.AutorunCheckBox.Text = 'Autorun';
            app.AnimationButton = uibutton(app.UIFigure, 'push');
            app.AnimationButton.Text = 'Animation';
            app.CustomPlotButton = uibutton(app.UIFigure, 'push');
            app.CustomPlotButton.Text = 'Custom Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end